classdef TransformData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SaveButton                 matlab.ui.control.Button
        FileLabel                  matlab.ui.control.Label
        EditYValuesEditField       matlab.ui.control.EditField
        EditYValuesEditFieldLabel  matlab.ui.control.Label
        EditXValuesEditField       matlab.ui.control.EditField
        EditXValuesEditFieldLabel  matlab.ui.control.Label
        ExportCSVButton            matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        PrevButton                 matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
    end


    properties (Access = private)
        index % Interval proerty to hold which data set we are focused on
        originalTable % Global property to hold cell array of all imported tables
        sets % Size value to hold how many sets of x and y data are populated
        currentData % Matrix (table2array) to hold each data set
        xdataNew % Property to hold process x data
        ydataNew % Property to hold processed y data
        file % Hold the name of the file we are looking at
        path % Hold the path of all the files (directory)
        xProcTable % Hold the array2table full variable of all files
        yProcTable % Hold the array2table full variable of all columns in all files
    end

    methods (Access = private)

        function populateTable(app)
            % Let the user know which file we are looking at
            app.FileLabel.Text = "";
            app.FileLabel.Text = "Looking at file: " + app.file(1,app.index);

            % Select current data we are looking at based on app.index
            currentdata = app.originalTable{1,app.index};

            % Find the variables and populate UITable
            vars = currentdata.Properties.VariableNames;
            currentdata = currentdata(:,vars);
            app.UITable.Data = currentdata;
            app.UITable.ColumnName = vars;

            % Process each modified data at the end of the UITable
            % Since we already calculated and turned array2table, just display
            for i = 1:size(app.ydataNew{1,app.index},2)
                % Add processed table columns to ends of the table and rename
                app.UITable.Data(:,end+1) = app.xProcTable{1,app.index};
                app.UITable.Data(:,end+1) = app.yProcTable{1,app.index}(:,i);

                app.UITable.ColumnName(end+1) = {"Processed XData" + i};
                app.UITable.ColumnName(end+1) = {"Processed YData" + i};
            end
        end


        function save_to_base(~,overwrite)
            %
            % save_to_base() copies all variables in the calling function to the base
            % workspace.  This makes it possible to examine a function's internal
            % variables from the Matlab command prompt after the calling function
            % terminates.
            %
            % If the optional argument "overwrite" is present and has a non-zero value,
            % variables in the workspace of the caller will overwrite variables in the
            % base workspace having the same name.  Otherwise, preexisting variables in
            % the base workspace will not be overwritten.
            %
            % Phillip M. Feldman

            % Version 1.1, 18 Aug. 2009: Added fix to exclude 'ans' variable.
            %
            % Version 1.0, 2 June 2009: Initial version.
            %
            % Note: I'd like to acknowledge assistance from Sean Little of the
            % MathWorks.

            ws_caller= evalin('caller','who()');

            if length(nargin) && overwrite
                % Overwrite preexisting variables:
                variables= ws_caller;
            else
                % Do not overwrite preexisting variables:
                ws_base= evalin('base','who()');
                variables= setdiff(ws_caller, ws_base);
            end

            for i= 1 : length(variables)
                if ~strcmp(variables{i},'ans')
                    tmp= evalin('caller',variables{i});
                    assignin('base',variables{i},tmp);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide some GUI objects
            app.EditXValuesEditField.Visible = 0;
            app.EditXValuesEditFieldLabel.Visible = 0;
            app.EditYValuesEditField.Visible = 0;
            app.EditYValuesEditFieldLabel.Visible = 0;

            % Set index to first file
            app.index = 1;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                [File,Path] = uigetfile( ...
                    {'*.txt;*.dat;*.csv',...
                    'Delimited Files (*.txt,*.dat,*.csv)';
                    '*.xls;*.xlsb;*.xlsm;*.xlsx;*.xltm;*.xltx;*.ods',...
                    'Spreadsheet Files (*.xls,*.xlsb,*.xlsm,*.xlsx,*.xltm,*.xltx,*.ods)';...
                    '*.*','All Files (*.*)'},'Select a File','MultiSelect','on');
            catch
                uialert(app.UIFigure,"Error loading your file. Please try again or a new file.","Error in file path.");
                return
            end

            if isa(File,"double") % This means the UI prompt was canceled
                return
            end

            % Reapply hidden GUI objects
            app.EditXValuesEditField.Visible = 1;
            app.EditXValuesEditFieldLabel.Visible = 1;
            app.EditYValuesEditField.Visible = 1;
            app.EditYValuesEditFieldLabel.Visible = 1;

            app.EditXValuesEditField.Value = "";
            app.EditYValuesEditField.Value = "";

            if isa(File,"cell") % Multiple files selected
                % Preallocate cell array to hold data and strings to hold name
                app.originalTable = cell(1,size(File,2));
                app.currentData = cell(1,size(File,2));
                app.file = strings(1,size(File,2));

                % Sift through all files
                for i = 1:size(File,2)

                    % Populate table variable with all file data sets
                    app.originalTable{1,i} = readtable([Path,File{1,i}],"VariableNamingRule","preserve");
                    app.originalTable{1,i} = rmmissing(app.originalTable{1,i});

                    % Turn table variables into arrays to process
                    app.currentData{1,i} = table2array(app.originalTable{1,i});

                    % Create empty rows of processed data to be changed in future
                    app.xdataNew{1,i} = zeros(size(app.originalTable{:,i},1),1);
                    app.ydataNew{1,i} = zeros(size(app.originalTable{:,i},1),1);

                    app.xProcTable{1,i} = array2table(app.xdataNew{1,i});
                    app.yProcTable{1,i} = array2table(app.ydataNew{1,i});

                    % This is to save every name to be displayed later
                    app.file(1,i) = convertCharsToStrings(File{1,i});

                end
            else % Only one file selected
                app.originalTable{1,1} = readtable([Path,File],"VariableNamingRule","preserve"); % Load in your data into a Matlab table
                app.originalTable{1,1} = rmmissing(app.originalTable{1,1});
                app.currentData = table2array(app.originalTable{1,1});

                % Create empty rows of processed data to be changed in future
                app.xdataNew{1,1} = zeros(size(app.originalTable,1),1);
                app.ydataNew{1,1} = zeros(size(app.originalTable,1),1);

                app.xProcTable{1,1} = array2table(app.xdataNew{1,1});
                app.yProcTable{1,1} = array2table(app.ydataNew{1,1});
                app.file = convertCharsToStrings(File);
            end

            % Populate variable with total number of datasets
            app.sets = size(app.originalTable,2);
            app.path = Path;

            % Update table
            populateTable(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.index = app.index + 1;
            if app.index > app.sets
                app.index = app.sets;
                return
            end

            populateTable(app);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Alter index accordingly and replot data
            app.index = app.index - 1;
            if app.index < 1
                app.index = 1;
                return
            end

            populateTable(app);
        end

        % Value changed function: EditXValuesEditField
        function EditXValuesEditFieldValueChanged(app, event)
            value = app.EditXValuesEditField.Value;
            change = str2double(value(2:end));

            for i = 1:app.sets
                % All xdata will be in first column and same throughout all other x columns so only do calc once per file
                % Matlab will find the first character to either add/subtract or multiply/divide
                if contains(value(1), "+")
                    app.xdataNew{:,i} = app.currentData{:,i}(:,1) + change;
                elseif contains(value(1), "-")
                    app.xdataNew{:,i} = app.currentData{:,i}(:,1) - change;
                elseif contains(value(1), "*")
                    app.xdataNew{:,i} = app.currentData{:,i}(:,1)*change;
                elseif contains(value, "/")
                    app.xdataNew{:,i} = app.currentData{:,i}(:,1)/change;
                end
                app.xProcTable{:,i} = array2table(app.xdataNew{:,i});
            end

            populateTable(app);
        end

        % Value changed function: EditYValuesEditField
        function EditYValuesEditFieldValueChanged(app, event)
            value = app.EditYValuesEditField.Value;
            change = str2double(value(2:end));

            for i = 1:app.sets
                % Loop through how ever many sets of data per file
                for ind = 2:2:size(app.currentData{:,i},2)
                    % Matlab will find the first character to either add/subtract or multiply/divide
                    if contains(value(1), "+")
                        app.ydataNew{:,i}(:,ind/2) = app.currentData{:,i}(:,ind) + change;
                    elseif contains(value(1), "-")
                        app.ydataNew{:,i}(:,ind/2) = app.currentData{:,i}(:,ind) - change;
                    elseif contains(value(1), "*")
                        app.ydataNew{:,i}(:,ind/2) = app.currentData{:,i}(:,ind)*change;
                    elseif contains(value, "/")
                        app.ydataNew{:,i}(:,ind/2) = app.currentData{:,i}(:,ind)/change;
                    end
                end
                app.yProcTable{:,i} = array2table(app.ydataNew{:,i});
            end

            populateTable(app);
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            % Write transformed data to new table to save
            newCell{1,app.sets} = [];

            totalYSets = 0;
            for i = 1:app.sets
                sz = size(app.ydataNew{1,i},2);
                totalYSets = totalYSets + sz;
            end
            %             newCell{1,totalYSets} = [];
            %
            %              for i = 1:app.sets
            %                 sz = size(app.ydataNew{1,i},2);
            %                 for ind = 1:sz
            %                         newCell{:,i}(:,1) = app.xdataNew{1,i};
            %                         newCell{:,i}(:,2) = app.ydataNew{1,i}(:,ind);
            %                 end
            %              end
            %              tst = newCell;
            %              tst2 = newCell{1,1};


            %             for i = 1:app.sets
            %                 sz = size(app.ydataNew{1,i},2);
            %                 for ind = 1:sz
            %                     newCell{:,i}(:,2*ind-1) = app.xdataNew{1,i};
            %                     newCell{:,i}(:,2*ind) = app.ydataNew{1,i}(:,ind);
            %                 end
            %             end

            %             newName = strcat(app.path,'Modified_',app.file(1,app.index));

            newPath = strcat(app.path,'Edits\');
            mkdir(newPath);
            count = 1;
            for i = 1:app.sets
                sz = size(app.ydataNew{1,i},2);
                for ind = 1:sz
                    newName = strcat(newPath,'Modified','DataSet',num2str(count),"_",app.file(1,i));

                    [i1,j1] = ndgrid(1:size(app.xdataNew{1,i},1),1:size(app.xdataNew{1,i},2));
                    [i2,j2] = ndgrid(1:size(app.ydataNew{1,i}(:,ind),1),(1:size(app.ydataNew{1,i}(:,ind),2))+size(app.xdataNew{1,i},2));
                    z = accumarray([i1(:),j1(:);i2(:),j2(:)],[app.xdataNew{1,i}(:);app.ydataNew{1,i}(:,ind)]);
                    writematrix(z,newName);
                    count = count + 1;
                end
            end


            %             newPath = strcat(app.path,'Edits\');
            %             mkdir(newPath);
            %             z{1,totalYSets} = [];
            %             count = 1;
            %             for i = 1:app.sets
            %                 sz = size(app.ydataNew{1,i},2);
            %                 for ind = 1:sz
            %
            %                     [i1,j1] = ndgrid(1:size(app.xdataNew{1,i},1),1:size(app.xdataNew{1,i},2));
            %                     [i2,j2] = ndgrid(1:size(app.ydataNew{1,i}(:,ind),1),(1:size(app.ydataNew{1,i}(:,ind),2))+size(app.xdataNew{1,i},2));
            %                     z{count} = accumarray([i1(:),j1(:);i2(:),j2(:)],[app.xdataNew{1,i}(:);app.ydataNew{1,i}(:,ind)]);
            %                     count = count + 1;
            %                 end
            %             end
            %
            %             newName = strcat(app.path,'Modified','DataSet',num2str(count),"_",app.file(1,app.index));
            %             writecell(z,newName);


            %             newName = strcat(app.path,'Modified_',app.file(1,app.index),".csv");

            %             writecell(newCell,newName);
            %             cell2csv(app,newName,newCell);
            %             cellwrite(app,newName,newCell);

            % Use the UITable instead of variable to concatenate properly
            %             app.UITable.Visible = 0;
            %             temp = app.index;
            %             for i = 1:app.sets
            %                 app.index = i;
            %                 populateTable(app);
            %                 writetable(app.UITable.Data,newName,"WriteMode","Append");
            %             end
            %             app.index = temp;
            %             populateTable(app);
            %             app.UITable.Visible = 1;

            % Let the user know where it was saved
            uialert(app.UIFigure,"The modification is done. The file was saved here: " + newName, "Transformation Finished","Icon",'success');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Call on these properties in order to save to workspace
            originalTable = app.originalTable;
            newXData = app.xdataNew;
            newYData = app.ydataNew;

            % Save vars of this function to base workspace
            save_to_base(app, 1);

            % Now save workspace to .mat file
            newPath = strcat(app.path,'Edits\');
            filename = "variables.mat";
            newName = strcat(newPath,filename);
            save(newName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Transform Data';
            app.UIFigure.Icon = 'logo.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'XData'; 'YData'; 'XData (Processed)'; 'YData (Processed)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExportCSVButton = uibutton(app.UIFigure, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.EditXValuesEditFieldLabel = uilabel(app.UIFigure);
            app.EditXValuesEditFieldLabel.Text = 'Edit X Values';
            app.EditXValuesEditField = uieditfield(app.UIFigure, 'text');
            app.EditXValuesEditField.ValueChangedFcn = createCallbackFcn(app, @EditXValuesEditFieldValueChanged, true);
            app.EditYValuesEditFieldLabel = uilabel(app.UIFigure);
            app.EditYValuesEditFieldLabel.Text = 'Edit Y Values';
            app.EditYValuesEditField = uieditfield(app.UIFigure, 'text');
            app.EditYValuesEditField.ValueChangedFcn = createCallbackFcn(app, @EditYValuesEditFieldValueChanged, true);
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = '';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransformData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end